classdef window_word < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Word      matlab.ui.control.Label
        Lamp      matlab.ui.control.Lamp
        Finish    matlab.ui.control.Label
    end


    methods (Access = private)
    
        function experiment(app, words, time_nothing, time_word, time_ligth, num_words)
            %variables para el sonido
            amp = 100000 
            fs = 20500  % sampling frequency
            duration = 0.02
            freq = 100000
            values = 0:1/fs:duration;
            beep = amp*sin(2*pi* freq*values)
            
            for i = 0:num_words
                %Tiempo inicial
                pause(time_nothing)
                %sonido                
                sound(beep)
                %Tiempo palabra
                ranWord = words(randi(size(words)))
                app.Word.Text = ranWord
                pause(time_word)
                app.Word.Text = ""
                %Tiempo Luz
                app.Lamp.Color = "red"
                pause(time_ligth)
                app.Lamp.Color = "black"
            end
            app.Finish.Text = "Finalizado"
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Variables
            words = ["Universidad" "Azul" "Bicicleta" "Petrosky" "Mover" "Levantar" "Rotar" "Persona" "Perro" "Girar" "Mesa"]
            time_nothing = 5
            time_word = 7
            time_ligth = 2
            app.Word.Text = ""
            num_words = 3
            experiment(app,words , time_nothing, time_word, time_ligth, num_words)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Word = uilabel(app.UIFigure);
            app.Lamp = uilamp(app.UIFigure);
            app.Finish = uilabel(app.UIFigure);
            app.Finish.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = window_word

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end